classdef InterfazVolcan < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        InstruccionesTab                matlab.ui.container.Tab
        Bienvenidoaestesimulador1Label  matlab.ui.control.Label
        SinFriccinTab                   matlab.ui.container.Tab
        Rangode0s1sLabel                matlab.ui.control.Label
        Rangode200ms500msLabel          matlab.ui.control.Label
        Rangode3080Label                matlab.ui.control.Label
        Rangode1000m6000mLabel          matlab.ui.control.Label
        SimularSinFriccinButton         matlab.ui.control.Button
        AlturadelVolcanEditField        matlab.ui.control.NumericEditField
        AlturadelVolcanEditFieldLabel   matlab.ui.control.Label
        AngulodeDisparoEditField        matlab.ui.control.NumericEditField
        AngulodeDisparoLabel            matlab.ui.control.Label
        VelocidadInicialEditField       matlab.ui.control.NumericEditField
        VelocidadInicialEditFieldLabel  matlab.ui.control.Label
        PasodetiempoEditField           matlab.ui.control.NumericEditField
        PasodetiempoEditFieldLabel      matlab.ui.control.Label
        ConFriccinTab                   matlab.ui.container.Tab
        Rangode800C1400CLabel           matlab.ui.control.Label
        TemperaturadelVolcanEditField   matlab.ui.control.NumericEditField
        TemperaturadelVolcanEditFieldLabel  matlab.ui.control.Label
        Rangode0a1Label                 matlab.ui.control.Label
        Masade10ka4000kgLabel           matlab.ui.control.Label
        Rangode300ms300msLabel          matlab.ui.control.Label
        FuerzadeVientoEditField         matlab.ui.control.NumericEditField
        FuerzadeVientoEditFieldLabel    matlab.ui.control.Label
        AreatransversalEditField        matlab.ui.control.NumericEditField
        AreatransversalEditFieldLabel   matlab.ui.control.Label
        MasaEditField                   matlab.ui.control.NumericEditField
        MasaEditFieldLabel              matlab.ui.control.Label
        CoeficientedeArrastreEditField  matlab.ui.control.NumericEditField
        CoeficientedeArrastreLabel      matlab.ui.control.Label
        SimularConfriccinButton         matlab.ui.control.Button
        IntroducetusdatosLabel          matlab.ui.control.Label
        CenterPanel                     matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
        RightPanel                      matlab.ui.container.Panel
        AlturaMaximaEditField           matlab.ui.control.NumericEditField
        AlturaMaximaEditFieldLabel      matlab.ui.control.Label
        LimpiarGraficaButton            matlab.ui.control.StateButton
        PyEditField_2                   matlab.ui.control.NumericEditField
        PyEditField_2Label              matlab.ui.control.Label
        PxEditField_2                   matlab.ui.control.NumericEditField
        PxEditField_2Label              matlab.ui.control.Label
        VyEditField_2                   matlab.ui.control.NumericEditField
        VyEditField_2Label              matlab.ui.control.Label
        VxEditField_2                   matlab.ui.control.NumericEditField
        VxEditField_2Label              matlab.ui.control.Label
        TiempoEditField_2               matlab.ui.control.NumericEditField
        TiempoEditField_2Label          matlab.ui.control.Label
        DatosdelultimoproyectilLanzadoLabel  matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SimularConfriccinButton
        function SimularConfriccinButtonPushed(app, event)
          
               volcan =app.UIAxes ;
               lin = animatedline(app.UIAxes);
               lin.Color = 'b'; %Color de la linea
               lin.LineStyle = "--";
            
               m = app.MasaEditField.Value; %Valor de la masa 
               Cd = app.CoeficientedeArrastreEditField.Value; %Valor de Coeficiente de Arrastre
               A =  app.AreatransversalEditField.Value; %Valor de Area Transversal
               y(1) = app.AlturadelVolcanEditField.Value; %Altura Inicial del Volcan
               V(1) = app.VelocidadInicialEditField.Value; %Se asigna la velocidad inicial
               Angulo(1) = app.AngulodeDisparoEditField.Value; %Se asigna el angulo inicial
               h        = app.PasodetiempoEditField.Value; %Paso de tiempo
               t        = 0; %tiempo
               Ta = 20; %Temperatura ambiente
               x(1)     = 0;
               Vx(1)    = V(1) * cosd(Angulo(1));
               Vy(1)    = V(1) * sind(Angulo(1));
               r(1)     = 0;
               T(1)     = app.TemperaturadelVolcanEditField.Value %Temperatura del Volcan
               P(1)     = 1.2*exp((-4.837e-26*9.8*y(1))/((1.380649e-23)*T(1))); %Calcular Denisdad De Aire
              
               k(1) = 0.5*P(1)*A*Cd;
               w = m * 9.8;
               ax(1) = (-(1/2)*P(1)*A*Cd*V(1)^2*cosd(Angulo(1))) / m;
               ay(1) = (-w - (1/2)*P(1)*A*Cd*V(1)^2*sind(Angulo(1))) / m;
               b = 1;
               Vw = app.FuerzadeVientoEditField.Value; %Asignamos la fuerza de viento

               %En caso de ser positvo o en contra de nuestro proyectil
               %cambiamos el valor de b
               if Vw > 0
                   b = 1
               end

               if Vw < 0
                   b = -1
               end


               error = 0;


               %Definimos el area para rango para las variables
               %Cuenta con un if, para poner los parametros. Y se asigna le
               %asigna el valor de error a 1 , ya que si error == 0, se
               %graficara.

               if Vw < -300 | Vw>300 %Rango de la fuerza de Viento
                   f = errordlg('La Velocidad del viento  no se encuentra dentro del rango correcto')
                   error = 1
               end    
               if  m > 500 | m < 0 %Rango de masa
                   f = errordlg('La masa no se encuentra dentro del rango correcto')
                   error = 1   
               end

               if  Angulo(1) > 80 | Angulo(1) < 30 %Angulo
                   f = errordlg('El angulo no se encuentra dentro del rango correcto')
                   error = 1   
               end

               if  y(1) > 6000 | y(1) < 1000 %Rango de la altura del volcan
                   f = errordlg('La altura del volcan no se encuentra dentro del rango correcto')
                   error = 1   
               end
               if  V(1) > 500 | V(1) < 200 %Rango de la Velocidad Inicial
                   f = errordlg('La velocidad inicial no se encuentra dentro del rango correcto')
                   error = 1   
               end
               if  h > 1 | h < 0.01
                   f = errordlg('El paso de tiempo no se encuentra dentro del rango correcto')
                   error = 1   
               end
               if  Cd > 1 | Cd < 0
                   f = errordlg('El coeficiente de fricción no se encuentra dentro del rango correcto')
                   error = 1   
               end
 
               if T(1) > 1400 | T(1) < 800
                   f = errordlg('La temperatura del volcan no se encuentra dentro del rango correcto')
                   error = 1   
               end

               if A > 1 | T(1) < 0
                   f = errordlg('La Area transversal no se encuentra dentro del rango correcto')
                   error = 1   
               end

            
               %Caso con fricción
               if error == 0
                   for i=1:1000
                       
                       x(i+1)  = x(i) + Vx(i)*h; %Formula para obtener la posición de x conforme pase el tiempo
                       y(i+1)  = y(i) + Vy(i)*h; %Formula para obtener la posición de y conforme pase el tiempo
    
                       Vx(i+1) = Vx(i) + ax(i)*h; %Formula para obtener la velocidad en x conforme pase el tiempo
                       Vy(i+1) = Vy(i) + ay(i)*h; %Formula para obtener la velocidad en y conforme pase el tiempo
    
                       V(i+1) = sqrt(Vx(i+1)^2 + Vy(i+1)^2); %Formula para obtener la magnitud de velocidad
                       Angulo(i+1) = atand(Vy(i+1)/Vx(i+1)); %Formula para obtener el angulo 
    
                       r(i+1) = sqrt(     (x(i+1)-x(i))^2 + (y(i+1)-y(i))^2    );
                       C = T(i)-Ta;
                       T(i+1) = C*exp(-0.00016*r(i+1))+ Ta ;%Calcular la temperatura conforme pase el tiempo
                       P(i+1) = 1.2*exp((-4.837e-26*9.8*y(i+1))/((1.380649e-23)*T(i+1))); %Formula para conocer la Densidad   del airere
                       
                                   
                       ax(i + 1) = ((-(0.5)*P(1)*A*Cd*V(i+1)^2*cosd(Angulo(i+1))) + (b*(0.5)*P(1)*A*Cd*Vw^2)) / m;
                       ay(i + 1) = (-w - (1/2)*P(i+1)*A*Cd*V(i+1)^2*sind(Angulo(i+1))) / m;
    
                       t(i+1) = t(i)+h;
                   
                       %Datos del proyectil
                       app.TiempoEditField_2.Value= t(i);
                       app.VxEditField_2.Value = Vx(i);
                       app.VyEditField_2.Value = Vy(i);
                       app.PxEditField_2.Value = x(i);
                       app.PyEditField_2.Value = y(i);
                       app.AlturaMaximaEditField.Value = max(y);


                       addpoints(lin,x(i),y(i));
                       drawnow
                    
                      if y(i)<= 0
                         break
                      end
                   end
               end

               if error == 1
               end


               %comet(app.UIAxes,x,y)
               hold(volcan)
               %comet(app.UIAxes,xs,ys)
               %daspect([1,1,1])


        end

        % Value changed function: LimpiarGraficaButton
        function LimpiarGraficaButtonValueChanged(app, event)
            value = app.LimpiarGraficaButton.Value;
            cla(app.UIAxes);
            
        end

        % Button pushed function: SimularSinFriccinButton
        function SimularSinFriccinButtonPushed(app, event)
            lin_2 = animatedline(app.UIAxes);
            lin_2.Color = 'r';
            lin_2.LineStyle = "--"; 
            volcan =app.UIAxes ;

            
             %Sin fricción
               xs = 0; %Posición x Inicial sin fricción
               ys = app.AlturadelVolcanEditField.Value; %Posicion y Inicial sin fricción
               Vs = app.VelocidadInicialEditField.Value; %Velocidad Inicial
               As = app.AngulodeDisparoEditField.Value; %Angulo Inicial
               axs = 0;
               ays = 9.8;
               vxs = Vs*cosd(As);
               vys = Vs*sind(As);
               error = 0;
               t     = 0; %tiempo
               h   = app.PasodetiempoEditField.Value; %Paso de tiempo

               if  As > 80 | As < 30
                   f = errordlg('El angulo no se encuentra dentro del rango correcto')
                   error = 1   
               end

               if  ys > 6000 | ys < 1000
                   f = errordlg('La altura del volcan no se encuentra dentro del rango correcto')
                   error = 1   
               end

               if  Vs > 500 | Vs < 200
                   f = errordlg('La velocidad inicial no se encuentra dentro del rango correcto')
                   error = 1   
               end
           

               if  h > 1 | h < 0.01
                   f = errordlg('El paso de tiempo no se encuentra dentro del rango correcto')
                   error = 1   
               end


              %Caso con fricción
               if error == 0
                  for i=1:1000
                       
                   %Caso sin fricción
                   xs(i+1)  = xs(i) + vxs(i)*h;
                   ys(i+1)  = ys(i) + vys(i)*h;
                   vxs(i+1) = vxs(i) + axs*h;
                   vys(i+1) = vys(i) - ays*h;
                   As(i+1) = atand(vys(i+1)/vxs(i+1));
                   t(i+1) = t(i)+h;
                 
                   %Datos sin fricción
                   app.TiempoEditField_2.Value= t(i);
                   app.VxEditField_2.Value = vxs(i);
                   app.VyEditField_2.Value = vys(i);
                   app.PxEditField_2.Value = xs(i);
                   app.PyEditField_2.Value = ys(i);
                   addpoints(lin_2,xs(i),ys(i));
                   app.AlturaMaximaEditField.Value = max(ys);
                   drawnow;

                   if ys(i)<= 0
                     break
                   end
                  end
               end

               if error == 1
               end

               hold (volcan)


        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {289, '1x', 333};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.IntroducetusdatosLabel = uilabel(app.LeftPanel);
            app.IntroducetusdatosLabel.Text = 'Introduce tus datos';
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.InstruccionesTab = uitab(app.TabGroup);
            app.InstruccionesTab.Title = 'Instrucciones';
            app.Bienvenidoaestesimulador1Label = uilabel(app.InstruccionesTab);
            app.Bienvenidoaestesimulador1Label.Text = {'Bienvenido al simulador :D'; ''; 'Sigue estas instrucciones para evitar errores'; 'en el código.'; ''; '1. Recuerda respetar los valores establecidos por'; ' el código, de lo contrario no graficara nada'; ''; '2. Recuerda que linea roja representa el tiro sin '; 'fricción, mientras que la linea azul si cuenta'; 'con fricción'; ''; '3. El simulador cuenta con 3 botones, '; 'Simular con Fricción / Simular sin Fricción /'; ' Borrar Gráfica'; ''; '4. Si la fuerza de viento es negativa, ira en contra '; 'del proyectil. Mientras que si es positiva ira a favor.'; ''; '5. Disfruta y realiza diferentes escenarios ;]'};
            app.SinFriccinTab = uitab(app.TabGroup);
            app.SinFriccinTab.Title = 'Sin Fricción';
            app.PasodetiempoEditFieldLabel = uilabel(app.SinFriccinTab);
            app.PasodetiempoEditFieldLabel.Text = 'Paso de tiempo';
            app.PasodetiempoEditField = uieditfield(app.SinFriccinTab, 'numeric');
            app.PasodetiempoEditField.Value = 0.5;
            app.VelocidadInicialEditFieldLabel = uilabel(app.SinFriccinTab);
            app.VelocidadInicialEditFieldLabel.Text = 'Velocidad Inicial';
            app.VelocidadInicialEditField = uieditfield(app.SinFriccinTab, 'numeric');
            app.VelocidadInicialEditField.Value = 200;
            app.AngulodeDisparoLabel = uilabel(app.SinFriccinTab);
            app.AngulodeDisparoLabel.Text = {'Angulo de '; 'Disparo'};
            app.AngulodeDisparoEditField = uieditfield(app.SinFriccinTab, 'numeric');
            app.AngulodeDisparoEditField.Value = 30;
            app.AlturadelVolcanEditFieldLabel = uilabel(app.SinFriccinTab);
            app.AlturadelVolcanEditFieldLabel.Text = 'Altura del Volcan';
            app.AlturadelVolcanEditField = uieditfield(app.SinFriccinTab, 'numeric');
            app.AlturadelVolcanEditField.Value = 2000;
            app.SimularSinFriccinButton = uibutton(app.SinFriccinTab, 'push');
            app.SimularSinFriccinButton.ButtonPushedFcn = createCallbackFcn(app, @SimularSinFriccinButtonPushed, true);
            app.SimularSinFriccinButton.Text = 'Simular Sin Fricción';
            app.Rangode1000m6000mLabel = uilabel(app.SinFriccinTab);
            app.Rangode1000m6000mLabel.Text = 'Rango de 1000m - 6000m';
            app.Rangode3080Label = uilabel(app.SinFriccinTab);
            app.Rangode3080Label.Text = 'Rango de 30º - 80º';
            app.Rangode200ms500msLabel = uilabel(app.SinFriccinTab);
            app.Rangode200ms500msLabel.Text = 'Rango de 200 m/s - 500m/s';
            app.Rangode0s1sLabel = uilabel(app.SinFriccinTab);
            app.Rangode0s1sLabel.Text = 'Rango de 0s - 1s';
            app.ConFriccinTab = uitab(app.TabGroup);
            app.ConFriccinTab.Title = 'Con Fricción';
            app.SimularConfriccinButton = uibutton(app.ConFriccinTab, 'push');
            app.SimularConfriccinButton.ButtonPushedFcn = createCallbackFcn(app, @SimularConfriccinButtonPushed, true);
            app.SimularConfriccinButton.Text = 'Simular Con fricción';
            app.CoeficientedeArrastreLabel = uilabel(app.ConFriccinTab);
            app.CoeficientedeArrastreLabel.Text = {'Coeficiente de'; ' Arrastre'};
            app.CoeficientedeArrastreEditField = uieditfield(app.ConFriccinTab, 'numeric');
            app.CoeficientedeArrastreEditField.Value = 0.5;
            app.MasaEditFieldLabel = uilabel(app.ConFriccinTab);
            app.MasaEditFieldLabel.Text = 'Masa';
            app.MasaEditField = uieditfield(app.ConFriccinTab, 'numeric');
            app.MasaEditField.Value = 100;
            app.AreatransversalEditFieldLabel = uilabel(app.ConFriccinTab);
            app.AreatransversalEditFieldLabel.Text = 'Area transversal';
            app.AreatransversalEditField = uieditfield(app.ConFriccinTab, 'numeric');
            app.AreatransversalEditField.Value = 0.5;
            app.FuerzadeVientoEditFieldLabel = uilabel(app.ConFriccinTab);
            app.FuerzadeVientoEditFieldLabel.Text = 'Fuerza de Viento';
            app.FuerzadeVientoEditField = uieditfield(app.ConFriccinTab, 'numeric');
            app.Rangode300ms300msLabel = uilabel(app.ConFriccinTab);
            app.Rangode300ms300msLabel.Text = 'Rango de -300 m/s - 300m/s';
            app.Masade10ka4000kgLabel = uilabel(app.ConFriccinTab);
            app.Masade10ka4000kgLabel.Text = 'Masa de 10k a 4000kg';
            app.Rangode0a1Label = uilabel(app.ConFriccinTab);
            app.Rangode0a1Label.Text = 'Rango de 0 a 1 ';
            app.TemperaturadelVolcanEditFieldLabel = uilabel(app.ConFriccinTab);
            app.TemperaturadelVolcanEditFieldLabel.Text = 'Temperatura del Volcan';
            app.TemperaturadelVolcanEditField = uieditfield(app.ConFriccinTab, 'numeric');
            app.TemperaturadelVolcanEditField.Value = 800;
            app.Rangode800C1400CLabel = uilabel(app.ConFriccinTab);
            app.Rangode800C1400CLabel.Text = 'Rango de 800º C - 1400º C';
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.CenterPanel);
            title(app.UIAxes, 'Simulador de volcan')
            xlabel(app.UIAxes, 'Distancia en X (m)')
            ylabel(app.UIAxes, 'Altura (m)')
            zlabel(app.UIAxes, 'Z')
            app.RightPanel = uipanel(app.GridLayout);
            app.DatosdelultimoproyectilLanzadoLabel = uilabel(app.RightPanel);
            app.DatosdelultimoproyectilLanzadoLabel.Text = 'Datos del ultimo proyectil Lanzado';
            app.TiempoEditField_2Label = uilabel(app.RightPanel);
            app.TiempoEditField_2Label.Text = 'Tiempo';
            app.TiempoEditField_2 = uieditfield(app.RightPanel, 'numeric');
            app.VxEditField_2Label = uilabel(app.RightPanel);
            app.VxEditField_2Label.Text = 'Vx';
            app.VxEditField_2 = uieditfield(app.RightPanel, 'numeric');
            app.VyEditField_2Label = uilabel(app.RightPanel);
            app.VyEditField_2Label.Text = 'Vy';
            app.VyEditField_2 = uieditfield(app.RightPanel, 'numeric');
            app.PxEditField_2Label = uilabel(app.RightPanel);
            app.PxEditField_2Label.Text = 'Px';
            app.PxEditField_2 = uieditfield(app.RightPanel, 'numeric');
            app.PyEditField_2Label = uilabel(app.RightPanel);
            app.PyEditField_2Label.Text = 'Py';
            app.PyEditField_2 = uieditfield(app.RightPanel, 'numeric');
            app.LimpiarGraficaButton = uibutton(app.RightPanel, 'state');
            app.LimpiarGraficaButton.ValueChangedFcn = createCallbackFcn(app, @LimpiarGraficaButtonValueChanged, true);
            app.LimpiarGraficaButton.Text = 'Limpiar Grafica';
            app.AlturaMaximaEditFieldLabel = uilabel(app.RightPanel);
            app.AlturaMaximaEditFieldLabel.Text = 'Altura Maxima';
            app.AlturaMaximaEditField = uieditfield(app.RightPanel, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = InterfazVolcan

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end